classdef salpPlotterGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SalpPlotterUIFigure      matlab.ui.Figure
        DataHandlingLabel        matlab.ui.control.Label
        SelectForceDropDown      matlab.ui.control.DropDown
        SelectSystemDropDown     matlab.ui.control.DropDown
        ExportDataButton         matlab.ui.control.Button
        AnimateButton            matlab.ui.control.Button
        ButtonGroup              matlab.ui.container.ButtonGroup
        GIFButton                matlab.ui.control.RadioButton
        VideoButton              matlab.ui.control.RadioButton
        ResultsAnimationLabel    matlab.ui.control.Label
        PlotButton               matlab.ui.control.Button
        ShapeMotionCheckBox      matlab.ui.control.CheckBox
        BodyMotionCheckBox       matlab.ui.control.CheckBox
        ResultsPlottingLabel     matlab.ui.control.Label
        SelectTrajectoryButton   matlab.ui.control.Button
        LockDurationButton       matlab.ui.control.StateButton
        DurationsEditField       matlab.ui.control.NumericEditField
        DurationsEditFieldLabel  matlab.ui.control.Label
        Panel                    matlab.ui.container.Panel
        SimulationSetupLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        programFiles % location of 'ProgramFiles' directory
        sysplotterHome % location of syplotter home directory
        salp % salp system definition
        forcedef % force profile definition

        salpDefined %boolean specifying whether a salp structure has been defined
        salpFile %String specifying name of salp definition file
        forceDefined %boolean specifying whether a force profile has been defined
        forceFile %String specifying name of force definition file

        tiling % tiling setup for current panel
        topAxis % handle for top axis in a subplot setup
        bottomAxis % handle for bottom axis in a subplot setup
        doingSubplots % boolean specifying whether we're doing subplots
        fullAxis % axis handle for a plot that takes up the full panel

        simOutOfDate % boolean deciding whether the simulation needs rerun
        simulationResults % structure holding simulation results for the salp
        ts % structure holding time vector for simulation

        NSaved % number of last files called to record
        systemsNameList %List of system names that can be loaded into the GUI
        systemsFileList %List of system file names that can loaded into the GUI
        forcesNameList %List of force names that can be loaded into the GUI
        forcesFileList %List of force file names that can be loaded into the GUI
        lastLoadedForceFile %string containing the file with last loaded forces
        lastLoadedSystemFile %string containing the file with last loaded systems
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Find directory containing this file
            stk = dbstack;
            filePath = which(stk(1).file);
            slashIndices = strfind(filePath,'\');
            %Find 'ProgramFiles' directory location
            app.programFiles = filePath(1:slashIndices(end-1));
            %Find Sysplotter home directory
            app.sysplotterHome = filePath(1:slashIndices(end-2));
            
            %Add necessary directories to path for later tool usage
            progFilesLaunchDirectories = {'Geometry','Physics','Utilities','SalpPlotter','sys_calcpath_fcns'};
            for i = 1:numel(progFilesLaunchDirectories)
                addpath(genpath([app.programFiles,progFilesLaunchDirectories{i}]));
            end

            %Add system and force definition directories to path
            addpath(genpath([app.sysplotterHome,'UserFiles\GenericUser\Systems']));

            %Initialize booleans to say salp GUI just started
            app.salpDefined = false;
            app.forceDefined = false;
            app.doingSubplots = false;
            app.simulationResults = false;
            app.ts = false;

            %Set number of files to save for recently loaded dropdowns
            app.NSaved = 3;

            %Check to see if the last systems file exists, if not create it
            lastSystemsFile = [app.sysplotterHome,'UserFiles\GenericUser\Systems\SalpSystems\lastLoadedSystems.mat'];
            if ~isfile(lastSystemsFile)
                prevSystems = {};
                prevSystemFiles = {};
                save(lastSystemsFile,'prevSystems','prevSystemFiles');
            end
            app.SelectSystemDropDown.Items = {'Select System'};

            %Check to see if the last force file exists, if not create it
            lastForcesFile = [app.sysplotterHome,'UserFiles\GenericUser\Systems\SalpForces\lastLoadedForces.mat'];
            if ~isfile(lastForcesFile)
                prevForces = {};
                prevForceFiles = {};
                save(lastForcesFile,'prevForces','prevForceFiles');
            end
            app.SelectForceDropDown.Items = {'Select Force'};

            %Store names of files for keeping track of last loaded systems
            %and files
            app.lastLoadedForceFile = lastForcesFile;
            app.lastLoadedSystemFile = lastSystemsFile;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            %If we have both a salp definition and a force definition
            if app.salpDefined && app.forceDefined
    
                %Check to see if we need to rerun the simulation, do it
                if app.simOutOfDate
                    [ts,simulationResults] = simulateSalpODE45(app.salp,app.forcedef,app.forcedef.duration);
                    app.simOutOfDate = false;
                    app.ts = ts;
                    app.simulationResults = simulationResults;
                %If not load recently simulated data
                else
                    ts = app.ts;
                    simulationResults = app.simulationResults;
                end

                %If we want only body motion, plot that bigly
                if app.BodyMotionCheckBox.Value && ~app.ShapeMotionCheckBox.Value
                    tiling = tiledlayout(app.Panel,1,1);
                    app.fullAxis = nexttile(tiling);
                    plotBodyMotion(app.fullAxis,simulationResults);
                    app.doingSubplots = false;
                    title(app.fullAxis,'Salp Motion Through Space');

                %If we want only shape motion, plot that bigly
                elseif ~app.BodyMotionCheckBox.Value && app.ShapeMotionCheckBox.Value
                    tiling = tiledlayout(app.Panel,1,1);
                    app.fullAxis = nexttile(tiling);
                    plotShapeMotion(app.fullAxis,ts,simulationResults);
                    app.doingSubplots = false;
                    title(app.fullAxis,'Salp Shape Motion');

                %If we want both, plot both
                elseif app.BodyMotionCheckBox.Value && app.ShapeMotionCheckBox.Value
                    tiling = tiledlayout(app.Panel,2,1);
                    app.topAxis = nexttile(tiling);
                    app.bottomAxis = nexttile(tiling);
                    plotBodyMotion(app.topAxis,simulationResults);
                    plotShapeMotion(app.bottomAxis,ts,simulationResults);
                    app.doingSubplots = true;
                    axis(app.topAxis,'on');
                    axis(app.bottomAxis,'on');
                    title(app.topAxis,'Salp Motion Through Space');
                    title(app.bottomAxis,'Salp Shape Motion');
                end

            else
                error('Either salp system or force definition has not been set.');

            end
        
        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)
            
            %Check to make sure we have both a salp and force definition
            if ~app.salpDefined || ~app.forceDefined

                error('Either salp system or force definition has not been set.');

            else
            
                %Make video file if requested
                if app.VideoButton.Value

                    %Get desired file name and storage location
                    startLocation = [app.sysplotterHome,'\UserFiles\GenericUser\Animations\*.mp4'];
                    f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0],'CloseRequestFcn','');
                    [vidName,vidPath] = uiputfile(startLocation);
                    delete(f);
    
                    %Rerun the simulation if necessary
                    if app.simOutOfDate
                        [ts,simulationResults] = simulateSalpODE45(app.salp,app.forcedef,app.forcedef.duration);
                        app.simOutOfDate = false;
                        app.ts = ts;
                        app.simulationResults = simulationResults;
                    else
                        ts = app.ts;
                        simulationResults = app.simulationResults;
                    end
                    %Make and save a video
                    makeSalpVideo(app.salp,app.forcedef,ts,simulationResults,[vidPath,vidName]);

                %If making a gif, do that similar to video
                elseif app.GIFButton.Value
                    startLocation = [app.sysplotterHome,'\UserFiles\GenericUser\Animations\*.gif'];
                    
                    f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0],'CloseRequestFcn','');
                    [gifName,gifPath] = uiputfile(startLocation);
                    delete(f);
    
                    if app.simOutOfDate
                        [ts,simulationResults] = simulateSalpODE45(app.salp,app.forcedef,app.forcedef.duration);
                        app.simOutOfDate = false;
                        app.ts = ts;
                        app.simulationResults = simulationResults;
                    else
                        ts = app.ts;
                        simulationResults = app.simulationResults;
                    end
                    makeSalpGif(app.salp,app.forcedef,ts,simulationResults,[gifPath,gifName]);
                end
            
            end
        end

        % Value changed function: DurationsEditField
        function DurationsEditFieldValueChanged(app, event)

            %Make sure the durations field makes sense
            if app.DurationsEditField.Value <= 0
                app.DurationsEditField.Value = 0;
                warning('Simulation duration must be positive. A value of zero uses the default set in the force file.');
            end

            %If move to subplot configuration if not already there
            if ~app.doingSubplots
                app.tiling = tiledlayout(app.Panel,2,1);
                app.topAxis = nexttile(app.tiling);
                app.bottomAxis = nexttile(app.tiling);
                if ~app.salpDefined
                    axis(app.topAxis,'off');
                end
                if ~app.forceDefined
                    axis(app.bottomAxis,'off');
                end
            end

            %Plot salp again just to be sure it's there
            if app.salpDefined
                axis(app.topAxis,'on');
                plotSalpFrame(app.topAxis,app.salp,app.salp.geometry.initialPose,app.salp.geometry.initialShape);
            end

            %Regenerate force definition given the new duration and plot
            if app.forceDefined
                axis(app.bottomAxis,'on');

                duration = app.DurationsEditField.Value;
                if app.salpDefined
                    eval(['forcedef = ',app.forceFile,'(''getForceDefinition'',',num2str(app.salp.geometry.thrusters.nThrusters),',',num2str(duration),');']);
                else
                    eval(['forcedef = ',app.forceFile,'(''getForceDefinition'',1,',num2str(duration),');']);
                end

                if ~app.LockDurationButton.Value
                    app.DurationsEditField.Value = forcedef.duration;
                end
                app.forcedef = forcedef;

                plotForceProfile(app.bottomAxis,app.forcedef);
            end

            %Mark simulation to be rerun
            app.simOutOfDate = true;
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            
            %Get .mat file to save data to
            startLocation = [app.sysplotterHome,'\UserFiles\GenericUser\SalpSystemData\*.mat'];
                    
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0],'CloseRequestFcn','');
            [fileName,filePath] = uiputfile(startLocation);
            fullPath = [filePath,fileName];
            delete(f);
    
            saveCommand = ['save ',fullPath];

            %If file was valid
            if fullPath
                %Rerun simulation if necessary, save results
                if app.simOutOfDate
                    if any(any(app.simulationResults))
                        warning('Simulation data is out of date with current settings.  Saving stored data anyway.');
                        simulationResults = app.simulationResults;
                        ts = app.ts;
                        saveCommand = [saveCommand,' simulationResults ts'];
                    else
                        warning('Simulation data does not exist. Attempting to save system and force data instead.');
                    end
                else
                    simulationResults = app.simulationResults;
                    ts = app.ts;
                    saveCommand = [saveCommand,' simulationResults ts'];
                end

                %Try to save salp structure
                if app.salpDefined
                    salp = app.salp;
                    saveCommand = [saveCommand,' salp'];
                end

                %Try to save force definition
                if app.forceDefined
                    forcedef = app.forcedef;
                    saveCommand = [saveCommand,' forcedef'];
                end

                eval(saveCommand);
            end
        end

        % Drop down opening function: SelectSystemDropDown
        function SelectSystemDropDownOpening(app, event)
            
            %Get all files in systems directory
            sysplotterHome = app.sysplotterHome;
            systemsFolder = [sysplotterHome,'\UserFiles\GenericUser\Systems\SalpSystems'];
            filesStructure = dir(systemsFolder);

            %Start systems dropdown list with top two entries
            systemsNameList = {'Select System','----------- Recently Used -----------'};
            systemsFileList = {'NO SIR','NO SIR'};

            %For every system we've recently loaded, add it to the list
            load(app.lastLoadedSystemFile);
            for i = 1:numel(prevSystems)
                systemsNameList{end+1} = prevSystems{i};
                systemsFileList{end+1} = prevSystemFiles{i};
            end

            %Add separator for all the rest of the files
            systemsNameList{end+1} = '----------- All Files -----------';
            systemsFileList{end+1} = 'NO SIR';

            %For every file in the systems directory, get the system name
            for i = 3:numel(filesStructure)
                fileName = filesStructure(i).name;
                if strcmpi(fileName(end-1:end),'.m')
                    fileName = fileName(1:end-2);
                    systemsFileList{end+1} = fileName;
                    eval(['systemName = ',fileName,'(''getName'');']);
                    systemsNameList{end+1} = systemName;
                end
            end
            
            %Save list of system names and file locations
            app.systemsNameList = systemsNameList;
            app.systemsFileList = systemsFileList;
            app.SelectSystemDropDown.Items = systemsNameList;
            
        end

        % Value changed function: SelectSystemDropDown
        function SelectSystemDropDownValueChanged(app, event)
            %System chosen
            value = app.SelectSystemDropDown.Value;
            %Find corresponding file name
            firstIndex = find(strcmp(app.systemsNameList,value));
            fileName = app.systemsFileList{firstIndex};

            %If a valid file was chosen
            if ~strcmpi(fileName,'NO SIR')
                %Load that salp
                definitionFile = fileName;
                eval(['salp = ',definitionFile,'(''getSwimmerSystem'');']);
                app.salp = salp;
                app.salpFile = definitionFile;
        
                %Move to subplots if not already there
                if ~app.doingSubplots
                    app.tiling = tiledlayout(app.Panel,2,1);
                    app.topAxis = nexttile(app.tiling);
                    app.bottomAxis = nexttile(app.tiling);
                    if ~app.forceDefined
                        axis(app.bottomAxis,'off');
                    end
                end
        
                %Plot forces
                if app.forceDefined
                    axis(app.bottomAxis,'on');
                    plotForceProfile(app.bottomAxis,app.forcedef);
                end

                axis(app.topAxis,'on');
                %Plot salp that was loaded
                plotSalpFrame(app.topAxis,salp,salp.geometry.initialPose,salp.geometry.initialShape);
                
                %Make sure GUI knows salp exists and information has been
                %updated
                app.salp = salp;
                app.salpDefined = true;
                app.doingSubplots = true;
                app.simOutOfDate = true;

                %Reconfigure structure holding last loaded systems
                %appropriately
                load(app.lastLoadedSystemFile);
                [prevSystems,prevSystemFiles] = arrangeSavedData(value,fileName,prevSystems,prevSystemFiles,app.NSaved);
                save(app.lastLoadedSystemFile,'prevSystems','prevSystemFiles');

            else

                %If no valid system was selected erase the old system
                %drawing
                if app.doingSubplots
                    cla(app.topAxis);
                    axis(app.topAxis,'off');
                    title(app.topAxis,'');
                end

                %Make sure GUI knows that no salp was defined
                app.salp = 'Undefined';
                app.salpDefined = false;
                app.simOutOfDate = true;

                %Default dropdown value
                app.SelectSystemDropDown.Value = 'Select System';

            end
        end

        % Drop down opening function: SelectForceDropDown
        function SelectForceDropDownOpening(app, event)
            
            %Get location of last loaded files structure
            sysplotterHome = app.sysplotterHome;
            forcesFolder = [sysplotterHome,'\UserFiles\GenericUser\Systems\SalpForces'];
            filesStructure = dir(forcesFolder);

            %Start structure of possible files with default top 2 items
            forcesNameList = {'Select Force','----------- Recently Used -----------'};
            forcesFileList = {'NO SIR','NO SIR'};

            %Load the file containing recently used forces
            load(app.lastLoadedForceFile);
            for i = 1:numel(prevForces)
                forcesNameList{end+1} = prevForces{i};
                forcesFileList{end+1} = prevForceFiles{i};
            end

            %Add separator between recent files and list of all files
            forcesNameList{end+1} = '----------- All Files -----------';
            forcesFileList{end+1} = 'NO SIR';

            %For every force file in the directory, get the labelled force
            %name and add it to the dropdown
            for i = 3:numel(filesStructure)
                fileName = filesStructure(i).name;
                if strcmpi(fileName(end-1:end),'.m')
                    fileName = fileName(1:end-2);
                    forcesFileList{end+1} = fileName;
                    eval(['forceName = ',fileName,'(''getName'');']);
                    forcesNameList{end+1} = forceName;
                end
            end
            
            %Save the data appropriately
            app.forcesNameList = forcesNameList;
            app.forcesFileList = forcesFileList;
            app.SelectForceDropDown.Items = forcesNameList;

        end

        % Value changed function: SelectForceDropDown
        function SelectForceDropDownValueChanged(app, event)
            %Chosen force function
            value = app.SelectForceDropDown.Value;
            %Get corresponding force file name
            firstIndex = find(strcmp(app.forcesNameList,value));
            fileName = app.forcesFileList{firstIndex};

            %If a valid force function was chosen
            if ~strcmpi(fileName,'NO SIR')

                definitionFile = fileName;

                %Get duration of file to be run if overridden
                if app.LockDurationButton.Value
                    duration = app.DurationsEditField.Value;
                else
                    %Set duration to default specified in the force file
                    duration = 0;
                end

                %Get force profile for all thrusters in salp definition
                if app.salpDefined
                    eval(['forcedef = ',definitionFile,'(''getForceDefinition'',',num2str(app.salp.geometry.thrusters.nThrusters),',',num2str(duration),');']);
                else
                    eval(['forcedef = ',definitionFile,'(''getForceDefinition'',1,',num2str(duration),');']);
                end

                %Save force definition and file
                app.forcedef = forcedef;
                app.forceFile = definitionFile;
    
                %Overwrite force duration if not locked down
                if ~app.LockDurationButton.Value
                    app.DurationsEditField.Value = forcedef.duration;
                end
                
                %Move to subplots if not already there
                if ~app.doingSubplots
                    app.tiling = tiledlayout(app.Panel,2,1);
                    app.topAxis = nexttile(app.tiling);
                    app.bottomAxis = nexttile(app.tiling);
                    if ~app.salpDefined
                        axis(app.topAxis,'off');
                    end
                end

                %Plot the salp again if possible
                if app.salpDefined
                    axis(app.topAxis,'on');
                    plotSalpFrame(app.topAxis,app.salp,app.salp.geometry.initialPose,app.salp.geometry.initialShape);
                end
    
                %Plot the force for the first thruster
                axis(app.bottomAxis,'on');
    
                plotForceProfile(app.bottomAxis,forcedef);
    
                app.forceDefined = true;
                app.doingSubplots = true;
                app.simOutOfDate = true;

                %Save recent forces appropriately
                load(app.lastLoadedForceFile);
                [prevForces,prevForceFiles] = arrangeSavedData(value,fileName,prevForces,prevForceFiles,app.NSaved);
                save(app.lastLoadedForceFile,'prevForces','prevForceFiles');

            else

                %Clear force profile if currently plotted
                if app.doingSubplots
                    cla(app.bottomAxis);
                    axis(app.bottomAxis,'off');
                    title(app.bottomAxis,'');
                end

                %Erase saved force definition
                app.forcedef = 'Undefined';
                app.forceDefined = false;
                app.simOutOfDate = true;

                app.SelectForceDropDown.Value = 'Select Force';

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SalpPlotterUIFigure = uifigure('Visible', 'off');
            app.SalpPlotterUIFigure.Name = 'SalpPlotter';
            app.SimulationSetupLabel = uilabel(app.SalpPlotterUIFigure);
            app.SimulationSetupLabel.Text = 'Simulation Setup';
            app.Panel = uipanel(app.SalpPlotterUIFigure);
            app.DurationsEditFieldLabel = uilabel(app.SalpPlotterUIFigure);
            app.DurationsEditFieldLabel.Text = 'Duration (s)';
            app.DurationsEditField = uieditfield(app.SalpPlotterUIFigure, 'numeric');
            app.DurationsEditField.ValueChangedFcn = createCallbackFcn(app, @DurationsEditFieldValueChanged, true);
            app.LockDurationButton = uibutton(app.SalpPlotterUIFigure, 'state');
            app.LockDurationButton.Text = {'Lock'; 'Duration'};
            app.SelectTrajectoryButton = uibutton(app.SalpPlotterUIFigure, 'push');
            app.SelectTrajectoryButton.Enable = 'off';
            app.SelectTrajectoryButton.Text = 'Select Trajectory';
            app.ResultsPlottingLabel = uilabel(app.SalpPlotterUIFigure);
            app.ResultsPlottingLabel.Text = 'Results Plotting';
            app.BodyMotionCheckBox = uicheckbox(app.SalpPlotterUIFigure);
            app.BodyMotionCheckBox.Text = 'Body Motion';
            app.ShapeMotionCheckBox = uicheckbox(app.SalpPlotterUIFigure);
            app.ShapeMotionCheckBox.Text = 'Shape Motion';
            app.PlotButton = uibutton(app.SalpPlotterUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ResultsAnimationLabel = uilabel(app.SalpPlotterUIFigure);
            app.ResultsAnimationLabel.Text = 'Results Animation';
            app.ButtonGroup = uibuttongroup(app.SalpPlotterUIFigure);
            app.VideoButton = uiradiobutton(app.ButtonGroup);
            app.VideoButton.Text = 'Video';
            app.VideoButton.Value = true;
            app.GIFButton = uiradiobutton(app.ButtonGroup);
            app.GIFButton.Text = 'GIF';
            app.AnimateButton = uibutton(app.SalpPlotterUIFigure, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = 'Animate';
            app.ExportDataButton = uibutton(app.SalpPlotterUIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Tooltip = {'Saves simulation data to .mat file, along with system and force structures'};
            app.ExportDataButton.Text = 'Export Data';
            app.SelectSystemDropDown = uidropdown(app.SalpPlotterUIFigure);
            app.SelectSystemDropDown.Items = {'Select System'};
            app.SelectSystemDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectSystemDropDownOpening, true);
            app.SelectSystemDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectSystemDropDownValueChanged, true);
            app.SelectSystemDropDown.Value = 'Select System';
            app.SelectForceDropDown = uidropdown(app.SalpPlotterUIFigure);
            app.SelectForceDropDown.Items = {'Select Force'};
            app.SelectForceDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectForceDropDownOpening, true);
            app.SelectForceDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectForceDropDownValueChanged, true);
            app.SelectForceDropDown.Value = 'Select Force';
            app.DataHandlingLabel = uilabel(app.SalpPlotterUIFigure);
            app.DataHandlingLabel.Text = 'Data Handling';
            % Show the figure after all components are created
            app.SalpPlotterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = salpPlotterGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SalpPlotterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SalpPlotterUIFigure)
        end
    end
end